classdef LaunchGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWindow            matlab.ui.Figure                   % UI Figure
        initializeSDs         matlab.ui.control.Button           % Initiali...
        sensorTest            matlab.ui.control.Button           % Sensor Test
        collectDataStart      matlab.ui.control.Button           % Collect ...
        exitDataCollect       matlab.ui.control.Button           % Exit Dat...
        output                matlab.ui.control.TextArea        
        readyLaunch1          matlab.ui.control.Button           % Ready La...
        readyLaunch2          matlab.ui.control.Button           % Ready La...
        launch                matlab.ui.control.Button           % Launch
        abort                 matlab.ui.control.Button           % Abort
        status                matlab.ui.control.Button           % Status
        daqSystemsPanel       matlab.ui.container.Panel          % DAQ System
        launchControlsPanel   matlab.ui.container.Panel          % Launch C...
        launchBoxPanel        matlab.ui.container.Panel          % Launch Box
        systemArm             matlab.ui.control.Button           % System Arm
        LabelLamp             matlab.ui.control.Label            % RL1
        rl1Lamp               matlab.ui.control.Lamp            
        LabelLamp2            matlab.ui.control.Label            % RL2
        rl2Lamp               matlab.ui.control.Lamp            
        igniterTest           matlab.ui.control.Button           % Buzzer
        n2OFillClose          matlab.ui.control.Button           % N2O Fill...
        n2OVentClose          matlab.ui.control.Button           % N2O Vent...
        oxygenFillClose       matlab.ui.control.Button           % Oxygen F...
        qdAirClose            matlab.ui.control.Button           % QD Air C...
        chA                   matlab.ui.control.Button           % Ch A
        igniterChannel        matlab.ui.control.Button           % Igniter ...
        n2OFillOpen           matlab.ui.control.Button           % N2O Fill...
        n2OVentOpen           matlab.ui.control.Button           % N2O Vent...
        oxygenFillOpen        matlab.ui.control.Button           % Oxygen F...
        qdAirOpen             matlab.ui.control.Button           % QD Air Open
        aux                   matlab.ui.control.Button           % AUX
        chB                   matlab.ui.control.Button           % Ch B
        systemArmLamp         matlab.ui.control.Lamp            
        igniterArmLamp        matlab.ui.control.Lamp            
        igniterContinuityLamp matlab.ui.control.Lamp            
        buzzerLamp            matlab.ui.control.Lamp            
        igniterArm            matlab.ui.control.Button           % Igniter Arm
        igniterContTest       matlab.ui.control.Button           % Igniter ...
        n2OFillOpenLamp       matlab.ui.control.Lamp            
        n2OVentOpenLamp       matlab.ui.control.Lamp            
        oxygenFillOpenLamp    matlab.ui.control.Lamp            
        qdAirOpenLamp         matlab.ui.control.Lamp            
        auxLamp               matlab.ui.control.Lamp            
        chALamp               matlab.ui.control.Lamp            
        n2OFillCloseLamp      matlab.ui.control.Lamp            
        n2OVentCloseLamp      matlab.ui.control.Lamp            
        oxygenFillCloseLamp   matlab.ui.control.Lamp            
        qdAirCloseLamp        matlab.ui.control.Lamp            
        igniterChannelLamp    matlab.ui.control.Lamp            
        chBLamp               matlab.ui.control.Lamp            
        commStartPanel        matlab.ui.container.Panel          % Communic...
        LabelNumericEditField matlab.ui.control.Label            % Comm Port
        commPort              matlab.ui.control.NumericEditField % [0 Inf]
        startComm             matlab.ui.control.Button           % Start Comm
        findPorts             matlab.ui.control.Button           % Find Ports
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % startComm button pushed function
        function startCommPushed(app)
            port = app.commPort.Value;
            portString = ['COM' num2str(port)];
            
            % Find a serial port object.
            obj1 = instrfind('Type', 'serial', 'Port', portString, 'Tag', '');
            
            % Create the serial port object if it does not exist
            % otherwise use the object that was found.
            if isempty(obj1)
                obj1 = serial(portString);
            else
                fclose(obj1);
                obj1 = obj1(1)
            end
            
            % Connect to instrument object, obj1.
            if isvalid(obj1)
                fopen(obj1);
            else
                outputString = app.output.Value;
                app.output.Value = ['Port not valid'; outputString];
            end
            
            % Configure instrument object, obj1.
            set(obj1, 'Terminator', {13,13});
            % Configure instrument object, obj1.
            set(obj1, 'Timeout', 10.0);
        end

        % findPorts button pushed function
        function findPortsButtonPushed(app)
            out = instrfind('Type', 'serial');
            str = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(out)
            outputString = app.output.Value;
            app.output.Value = [str; outputString];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWindow = uifigure;
            app.mainWindow.Name = 'UI Figure';
            setAutoResize(app, app.mainWindow, true)
            app.output = uitextarea(app.mainWindow);
            app.daqSystemsPanel = uipanel(app.mainWindow);
            app.daqSystemsPanel.Title = 'DAQ System';
            app.daqSystemsPanel.Units = 'pixels';
            app.initializeSDs = uibutton(app.daqSystemsPanel, 'push');
            app.initializeSDs.Text = 'Initialize SDs';
            app.sensorTest = uibutton(app.daqSystemsPanel, 'push');
            app.sensorTest.Text = 'Sensor Test';
            app.collectDataStart = uibutton(app.daqSystemsPanel, 'push');
            app.collectDataStart.Text = 'Collect Data Start';
            app.exitDataCollect = uibutton(app.daqSystemsPanel, 'push');
            app.exitDataCollect.Text = 'Exit Data Collect';
            app.launchControlsPanel = uipanel(app.mainWindow);
            app.launchControlsPanel.Title = 'Launch Controls';
            app.launchControlsPanel.Units = 'pixels';
            app.readyLaunch1 = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch1.Text = 'Ready Launch 1';
            app.readyLaunch2 = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch2.Text = 'Ready Launch 2';
            app.launch = uibutton(app.launchControlsPanel, 'push');
            app.launch.Text = 'Launch';
            app.abort = uibutton(app.launchControlsPanel, 'push');
            app.abort.Text = 'Abort';
            app.status = uibutton(app.launchControlsPanel, 'push');
            app.status.Text = 'Status';
            app.LabelLamp = uilabel(app.launchControlsPanel);
            app.LabelLamp.Text = 'RL1';
            app.rl1Lamp = uilamp(app.launchControlsPanel);
            app.LabelLamp2 = uilabel(app.launchControlsPanel);
            app.LabelLamp2.Text = 'RL2';
            app.rl2Lamp = uilamp(app.launchControlsPanel);
            app.launchBoxPanel = uipanel(app.mainWindow);
            app.launchBoxPanel.Title = 'Launch Box';
            app.launchBoxPanel.Units = 'pixels';
            app.systemArm = uibutton(app.launchBoxPanel, 'push');
            app.systemArm.Text = 'System Arm';
            app.igniterTest = uibutton(app.launchBoxPanel, 'push');
            app.igniterTest.Text = 'Buzzer';
            app.n2OFillClose = uibutton(app.launchBoxPanel, 'push');
            app.n2OFillClose.Text = 'N2O Fill Close';
            app.n2OVentClose = uibutton(app.launchBoxPanel, 'push');
            app.n2OVentClose.Text = 'N2O Vent Close';
            app.oxygenFillClose = uibutton(app.launchBoxPanel, 'push');
            app.oxygenFillClose.Text = 'Oxygen Fill Close';
            app.qdAirClose = uibutton(app.launchBoxPanel, 'push');
            app.qdAirClose.Text = 'QD Air Close';
            app.chA = uibutton(app.launchBoxPanel, 'push');
            app.chA.Text = 'Ch A';
            app.igniterChannel = uibutton(app.launchBoxPanel, 'push');
            app.igniterChannel.Text = 'Igniter Channel';
            app.n2OFillOpen = uibutton(app.launchBoxPanel, 'push');
            app.n2OFillOpen.Text = 'N2O Fill Open';
            app.n2OVentOpen = uibutton(app.launchBoxPanel, 'push');
            app.n2OVentOpen.Text = 'N2O Vent Open ';
            app.oxygenFillOpen = uibutton(app.launchBoxPanel, 'push');
            app.oxygenFillOpen.Text = 'Oxygen Fill Open';
            app.qdAirOpen = uibutton(app.launchBoxPanel, 'push');
            app.qdAirOpen.Text = 'QD Air Open';
            app.aux = uibutton(app.launchBoxPanel, 'push');
            app.aux.Text = 'AUX';
            app.chB = uibutton(app.launchBoxPanel, 'push');
            app.chB.Text = 'Ch B';
            app.systemArmLamp = uilamp(app.launchBoxPanel);
            app.buzzerLamp = uilamp(app.launchBoxPanel);
            app.igniterContinuityLamp = uilamp(app.launchBoxPanel);
            app.igniterArmLamp = uilamp(app.launchBoxPanel);
            app.igniterArm = uibutton(app.launchBoxPanel, 'push');
            app.igniterArm.Text = 'Igniter Arm';
            app.igniterContTest = uibutton(app.launchBoxPanel, 'push');
            app.igniterContTest.Text = 'Igniter Cont Test';
            app.n2OFillOpenLamp = uilamp(app.launchBoxPanel);
            app.n2OVentOpenLamp = uilamp(app.launchBoxPanel);
            app.oxygenFillOpenLamp = uilamp(app.launchBoxPanel);
            app.qdAirOpenLamp = uilamp(app.launchBoxPanel);
            app.auxLamp = uilamp(app.launchBoxPanel);
            app.chALamp = uilamp(app.launchBoxPanel);
            app.n2OFillCloseLamp = uilamp(app.launchBoxPanel);
            app.n2OVentCloseLamp = uilamp(app.launchBoxPanel);
            app.oxygenFillCloseLamp = uilamp(app.launchBoxPanel);
            app.qdAirCloseLamp = uilamp(app.launchBoxPanel);
            app.igniterChannelLamp = uilamp(app.launchBoxPanel);
            app.chBLamp = uilamp(app.launchBoxPanel);
            app.commStartPanel = uipanel(app.mainWindow);
            app.commStartPanel.Title = 'Communication Intialization';
            app.commStartPanel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.commStartPanel);
            app.LabelNumericEditField.Text = 'Comm Port';
            app.commPort = uieditfield(app.commStartPanel, 'numeric');
            app.commPort.Limits = [0 Inf];
            app.startComm = uibutton(app.commStartPanel, 'push');
            app.startComm.ButtonPushedFcn = createCallbackFcn(app, @startCommPushed);
            app.startComm.Text = 'Start Comm';
            app.findPorts = uibutton(app.commStartPanel, 'push');
            app.findPorts.ButtonPushedFcn = createCallbackFcn(app, @findPortsButtonPushed);
            app.findPorts.Text = 'Find Ports';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LaunchGUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWindow)
        end
    end
end